classdef data_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        LOADFILEButton                  matlab.ui.control.Button
        XDataDropDownLabel              matlab.ui.control.Label
        XDataDropDown                   matlab.ui.control.DropDown
        YDataDropDownLabel              matlab.ui.control.Label
        YDataDropDown                   matlab.ui.control.DropDown
        startatthiscommentListBoxLabel  matlab.ui.control.Label
        startatthiscommentListBox       matlab.ui.control.ListBox
        endatthiscommentListBoxLabel    matlab.ui.control.Label
        endatthiscommentListBox         matlab.ui.control.ListBox
        dXstartEditFieldLabel           matlab.ui.control.Label
        dXstartEditField                matlab.ui.control.NumericEditField
        dXendEditFieldLabel             matlab.ui.control.Label
        dXendEditField                  matlab.ui.control.NumericEditField
        filterdataCheckBox              matlab.ui.control.CheckBox
        cutofffrequencyEditFieldLabel   matlab.ui.control.Label
        cutofffrequencyEditField        matlab.ui.control.NumericEditField
        RightPanel                      matlab.ui.container.Panel
        selectedfileEditFieldLabel      matlab.ui.control.Label
        selectedfileEditField           matlab.ui.control.EditField
        yUnitsDropDownLabel             matlab.ui.control.Label
        yUnitsDropDown                  matlab.ui.control.DropDown
        xUnitsDropDownLabel             matlab.ui.control.Label
        xUnitsDropDown                  matlab.ui.control.DropDown
        AutoscaleYCheckBox              matlab.ui.control.CheckBox
        YMaxEditFieldLabel              matlab.ui.control.Label
        YMaxEditField                   matlab.ui.control.NumericEditField
        YMinEditFieldLabel              matlab.ui.control.Label
        YMinEditField                   matlab.ui.control.NumericEditField
        ExludevaluesgreaterthanCheckBox  matlab.ui.control.CheckBox
        YMagnitudeEditFieldLabel        matlab.ui.control.Label
        YMagnitudeEditField             matlab.ui.control.NumericEditField
        UIAxes_all                      matlab.ui.control.UIAxes
        UIAxes_selection                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        daqdata % store the output of the loaded mat file into this property
        currentViewState % a string that is used to set the state of the app (editable, visible, etc)
        DATA % formatted data ouputs into columns with following format (time, data, comments)
        labelFontSize = 10 % default option for the font size in figure windows
        yScale = 1 % scalar multiple to apply to the y-axis data, if desired
        xScale = 1 % scalar multiple to apply to the x-axis data, if desired
        sIDX1 % current view selection index for the left hand side of the window
        sIDX2 % current view selection index for the right side of the window
        start_commentIDX = 1 % the index of the start selection based on comments
        end_commentIDX = 1 % the index of the end selection based on comments
    end
    
    methods (Access = private)
        
        function setAppViewState(app)
            % set the state of the app here
            
            switch app.currentViewState
                case 'load'
                    app.XDataDropDown.Enable = 'off';
                    app.YDataDropDown.Enable = 'off';
                    app.startatthiscommentListBox.Enable = 'off';
                    app.endatthiscommentListBox.Enable = 'off';
                    app.dXstartEditField.Enable = 'off';
                    app.dXendEditField.Enable = 'off';
                    app.filterdataCheckBox.Enable = 'off';
                    app.cutofffrequencyEditField.Enable = 'off';
                    app.AutoscaleYCheckBox.Enable = 'off';
                    app.YMinEditField.Enable = 'off';
                    app.YMaxEditField.Enable = 'off';
                    app.ExludevaluesgreaterthanCheckBox.Enable = 'off';
                    app.YMagnitudeEditField.Enable = 'off';
                case 'default'
                    app.XDataDropDown.Enable = 'on';
                    app.YDataDropDown.Enable = 'on';
                    app.startatthiscommentListBox.Enable = 'on';
                    app.endatthiscommentListBox.Enable = 'on';
                    app.dXstartEditField.Enable = 'on';
                    app.dXendEditField.Enable = 'on';
                    app.filterdataCheckBox.Enable = 'on';
                    if app.filterdataCheckBox.Value
                        app.cutofffrequencyEditField.Enable = 'on';
                    else
                        app.cutofffrequencyEditField.Enable = 'off';
                    end
                    app.AutoscaleYCheckBox.Enable = 'on';
                    app.YMinEditField.Enable = 'on';
                    app.YMaxEditField.Enable = 'on';
                    app.ExludevaluesgreaterthanCheckBox.Enable = 'on';
                    app.YMagnitudeEditField.Enable = 'on';
                otherwise
                    disp('the app view state passed to the setAppViewState does not match any cases')
            end
        end
        
        function updateAxes(app)
            % function that plots user selected data in the live axes
            x_opts = app.XDataDropDown.Items;
            x_sel = app.XDataDropDown.Value;
            
            y_opts = app.YDataDropDown.Items;
            y_sel = app.YDataDropDown.Value;
            
            xIDX = find(strcmp(x_sel,x_opts));
            yIDX = find(strcmp(y_sel,y_opts));
            
            % make a simple variable to store plot data
            D = app.DATA;
            x = D(:,xIDX).*app.xScale;
            y = D(:,yIDX).*app.yScale;
            
            % if the user wants to remove values above or below a
            % threshold, do this now
            if app.ExludevaluesgreaterthanCheckBox.Value
                remove_idx = abs(y)>app.YMagnitudeEditField.Value;
                x(remove_idx) = [];
                y(remove_idx) = [];
            end      

            % check that the selections happen in the correct order
            if app.start_commentIDX>=app.end_commentIDX
                % plot a darker line if no selection has been made
                plot(app.UIAxes_all,x,y,'-',...
                    'LineWidth',2,...
                    'Color','blue')
                % clear any selection that is already present in the app
                cla(app.UIAxes_selection)
                
            else
                % plot a lighter version of the line if a selection is made
                plot(app.UIAxes_all,x,y,'-',...
                    'LineWidth',2,...
                    'Color','cyan')
                
                % get the current index selections from here
                i1 = app.sIDX1;
                i2 = app.sIDX2;
                
                % determine how much to adjust i1 and i2 based on user
                % settings and current x scale (time)
                dX1 = app.dXstartEditField.Value;
                dX2 = app.dXendEditField.Value;
                
                % get the sampling rate (samples/s)
                rate = app.daqdata.metadata.Rate;
                % scale to appropriate units (samples/time)
                rate = rate/app.xScale;
                
                % calculate the number of samples needed to adjust by the
                % specified duration (time)*(samples/time) = samples
                dS1 = round(dX1*rate);
                dS2 = round(dX2*rate);
                
                % scale the indicies by the appropriate amount
                i1 = i1+dS1;
                i2 = i2+dS2;
                
                % force the minimum and maximum values to 1 and length of
                % array
                if i1<1; i1=1; end
                if i2<i1; i2=i1; end
                if i2>length(x); i2=length(x); end
                if i1>i2; i1 = i2; end
                
                % get the selected region and scale as necessary
                xSel = D(i1:i2,xIDX).*app.xScale;
                ySel = D(i1:i2,yIDX).*app.yScale;
                
                % remove outliers if the user desires
                if app.ExludevaluesgreaterthanCheckBox.Value
                    remove_idx = abs(ySel)>app.YMagnitudeEditField.Value;
                    xSel(remove_idx) = [];
                    ySel(remove_idx) = [];
                end
                
                % low pass filter the data if the user desires
                if app.filterdataCheckBox.Value
%                     ySel = lowpass(ySel,app.cutofffrequencyEditField.Value,rate);
%                     ySel = smoothdata(ySel,...
%                         'SmoothingFactor',app.cutofffrequencyEditField.Value);
                    fs = app.daqdata.metadata.Rate;
                    fc = app.cutofffrequencyEditField.Value;
                    [b,a] = butter(5,fc/(fs/2),'low');
                    ySel = filtfilt(b,a,ySel);
                end
                
                % PLOT (selection) -----------------------------------
                plot(app.UIAxes_selection,xSel,ySel,...
                    'Linewidth',1,...
                    'Color','blue')
                            
                % highlight the section of data shown in the short plot
                hold(app.UIAxes_all,'on')
                plot(app.UIAxes_all,xSel,ySel,'-',...
                    'Color','blue',...
                    'LineWidth',1)
                hold(app.UIAxes_all,'off')
                
                % mark the MAX point on the selection diagram
                markY = max(ySel);
                xlocY = xSel(find(ySel==markY));
                message = strcat('\leftarrow Max:', sprintf(' %.3g',markY));
                text(app.UIAxes_selection,xlocY(1),markY,message,...
                    'FontSize',app.labelFontSize,...
                    'BackgroundColor','white',...
                    'Margin',0.1)
                
                % mark the MIN point on the selection diagram
                markY = min(ySel);
                xlocY = xSel(find(ySel==markY));
                message = strcat('\leftarrow Min:', sprintf(' %.3g',markY));
                text(app.UIAxes_selection,xlocY(1),markY,message,...
                    'FontSize',app.labelFontSize,...
                    'BackgroundColor','white',...
                    'Margin',0.1)          
            end
            
            % find the indicies of all comments in the time series
            cIDX = find(D(:,end)); % assume comments are in final column
            cLabel = string(D(cIDX,end));    
            
            % plot the labels on the main plot with vertical lines
            for i = 1:length(cIDX)
                xline(app.UIAxes_all,D(cIDX(i),xIDX)*app.xScale,'-.k',cLabel(i),...
                    'LabelOrientation','aligned',...
                    'LabelHorizontalAlignment','center',...
                    'FontSize',app.labelFontSize)
            end
            
            % adjust axis properties
            axis(app.UIAxes_selection,'padded')
            if app.AutoscaleYCheckBox.Value
                axis(app.UIAxes_all,'padded')
%                 axis(app.UIAxes_selection,'padded')
            else
                ylim(app.UIAxes_all,[app.YMinEditField.Value app.YMaxEditField.Value])
%                 ylim(app.UIAxes_selection,[app.YMinEditField.Value app.YMaxEditField.Value])
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % run the following at start up
            
            % set the initial view state
            app.currentViewState = 'load';
            setAppViewState(app);
            
            % set the autoscale to on and disable the manual axis values
            app.YMaxEditField.Enable = 'off';
            app.YMinEditField.Enable = 'off';
            
            % set the data exclusion property enable here
            app.YMagnitudeEditField.Enable = 'off';
            
            % update the text box to prompt the user to select a file
            app.selectedfileEditField.Value = 'Click LOAD to select a file.';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {877, 877};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {283, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: LOADFILEButton
        function LOADFILEButtonPushed(app, event)
            % have the user select a file to load and analyze
            [file,path] = uigetfile('*.mat');
            if isequal(file,0)
               disp('User selected Cancel during load file')
            else
                % load the selected file
               D = load(fullfile(path,file));
               
               % check to see if the loaded file is in the structure that
               % we expect for the daq data
               if ~(isfield(D,'data') && isfield(D,'metadata') && isfield(D,'timestamps'))
                   message = strcat(file,' does not include the required structure fields! Select another file.');
                   app.selectedfileEditField.Value = message;
                   disp(message)
                   return
               end
               
               % if the file includes the expected field properties, then
               % store the structure to an app property
               app.daqdata = D;
               
               % display the selected file path in the text panel
               message = fullfile(path,file);
               app.selectedfileEditField.Value = message;
               
               % build a list of strings that describe the user options for
               % the x and y data
               dd_options = [{'time'}; D.metadata.ChannelName(1:end-1)'];
               app.XDataDropDown.Items = dd_options;
               app.XDataDropDown.Value = dd_options(1); % default value
               app.YDataDropDown.Items = dd_options;
               app.YDataDropDown.Value = dd_options(2); % default value
               
               % format the data into columns that match the order of the
               % dropdown options
               app.DATA = [D.timestamps D.data];
               
               % format the comment options for the selection boxes
               nComments = length(D.metadata.Comments(:,1));
               comment_options = cell(nComments,1);
               for i = 1:nComments
                   comment_options{i} = strjoin(string(D.metadata.Comments(i,[1 end])),{': '});
               end
               
               % pre-pend and post-pend options to the string for the
               % "start" and "end" options
               first_option = {'0: start'};
               last_option = strcat(num2str(D.metadata.Comments{end,1}+1),': end');
               comment_options = [first_option; comment_options; last_option];
               
               % set the options
               app.startatthiscommentListBox.Items = cellstr(comment_options);
               app.endatthiscommentListBox.Items = cellstr(comment_options);
               
               % set the range for the low pass filter and set the value to
               % 10% of the sampling rate
               app.cutofffrequencyEditField.Limits = [1e-6 app.daqdata.metadata.Rate/2-0.1];
               app.cutofffrequencyEditField.Value = app.daqdata.metadata.Rate/2*0.05;
               
               % set the app view state to allow the user to make
               % selections based on the selected file
               app.currentViewState = 'default';
               setAppViewState(app);
               
               % refresh the plots with the default selections
               updateAxes(app);
            end
        end

        % Value changed function: XDataDropDown, YDataDropDown
        function XorYDataDropDownValueChanged(app, event)
            updateAxes(app);
        end

        % Value changed function: endatthiscommentListBox, 
        % startatthiscommentListBox
        function commentListBoxValueChanged(app, event)
            % get all options from the dialog box
            comment_options = app.startatthiscommentListBox.Items;
            
            % get the current selections for both dialog boxes
            start_sel = app.startatthiscommentListBox.Value;
            end_sel = app.endatthiscommentListBox.Value;
            
            % assign the start and end comment indicies to the app
            % properties (enables the highlight mode in the main window, if
            % selected
            app.start_commentIDX = find(strcmp(start_sel,comment_options));
            app.end_commentIDX = find(strcmp(end_sel,comment_options)); 
            
            % get the index of the selected start and end comments
            startIDX = char(comment_options{app.start_commentIDX});
            startIDX = str2double(extractBefore(startIDX,':'));
            
            endIDX = char(comment_options{app.end_commentIDX});
            endIDX = str2double(extractBefore(endIDX,':'));
            
            % now we need to find the corresponding location in the
            % comment time series
            comments = app.DATA(:,end); % assuming comments are in the last column
            % add the "end" comment in the last element of the comment
            % array
            comments(end) = max(comments)+1;
            app.sIDX1 = find(comments==startIDX);
            app.sIDX2 = find(comments==endIDX);
            
            % refresh plots
            updateAxes(app); 
        end

        % Value changed function: yUnitsDropDown
        function yUnitsDropDownValueChanged(app, event)
            value = app.yUnitsDropDown.Value;
            
            % switch the scalar value if the dropdown was adjusted and
            % refresh the plots
            switch value
                case 'Volts (V)'
                    app.yScale = 1;
                case 'Millivolts (mV)'
                    app.yScale = 1e3;
                case 'Microvolts (uV)'
                    app.yScale = 1e6;
                otherwise
                    disp('invalid y units drop down selected!')
                    app.yScale = 1; 
            end
            
            updateAxes(app);
            
        end

        % Value changed function: xUnitsDropDown
        function xUnitsDropDownValueChanged(app, event)
            value = app.xUnitsDropDown.Value;
            
            % switch the scalar value if the dropdown was adjusted and
            % refresh the plots
            switch value
                case 'Seconds (s)'
                    app.xScale = 1;
                case 'Minutes (min)'
                    app.xScale = 1/60;
                case 'Hours (hr)'
                    app.xScale = 1/60/60;
                otherwise
                    disp('invalid x units drop down selected!')
                    app.xScale = 1; 
            end
            
            updateAxes(app);
        end

        % Value changed function: AutoscaleYCheckBox
        function AutoscaleYCheckBoxValueChanged(app, event)
            isEnabled = app.AutoscaleYCheckBox.Value;
            
            if isEnabled
                app.YMaxEditField.Enable = 'off';
                app.YMinEditField.Enable = 'off';
            else
                app.YMaxEditField.Enable = 'on';
                app.YMinEditField.Enable = 'on';
            end
            
            updateAxes(app);
        end

        % Value changed function: YMaxEditField
        function YMaxEditFieldValueChanged(app, event)
            maxY = app.YMaxEditField.Value;
            minY = app.YMinEditField.Value;
            prevY = event.PreviousValue;
            
            if maxY<=minY
                app.YMaxEditField.Value = prevY;
            end
            
            updateAxes(app);
        end

        % Value changed function: YMinEditField
        function YMinEditFieldValueChanged(app, event)
            maxY = app.YMaxEditField.Value;
            minY = app.YMinEditField.Value;
            prevY = event.PreviousValue;
            
            if maxY<=minY
                app.YMinEditField.Value = prevY;
            end
            
            updateAxes(app);
        end

        % Value changed function: ExludevaluesgreaterthanCheckBox
        function ExludevaluesgreaterthanCheckBoxValueChanged(app, event)
            value = app.ExludevaluesgreaterthanCheckBox.Value;
            
            if value
                app.YMagnitudeEditField.Enable = 'on';
            else
                app.YMagnitudeEditField.Enable = 'off';
            end
            
            updateAxes(app);
        end

        % Value changed function: YMagnitudeEditField
        function YMagnitudeEditFieldValueChanged(app, event)
%             value = app.YMagnitudeEditField.Value;
            updateAxes(app);
        end

        % Value changed function: dXstartEditField
        function dXstartEditFieldValueChanged(app, event)
            updateAxes(app);
        end

        % Value changed function: dXendEditField
        function dXendEditFieldValueChanged(app, event)
            updateAxes(app);
        end

        % Value changed function: filterdataCheckBox
        function filterdataCheckBoxValueChanged(app, event)
            value = app.filterdataCheckBox.Value;
            
            if value
                app.cutofffrequencyEditField.Enable = 'on';
            else
                app.cutofffrequencyEditField.Enable = 'off';
            end
            
            updateAxes(app);
        end

        % Value changed function: cutofffrequencyEditField
        function cutofffrequencyEditFieldValueChanged(app, event)
            updateAxes(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LOADFILEButton = uibutton(app.LeftPanel, 'push');
            app.LOADFILEButton.ButtonPushedFcn = createCallbackFcn(app, @LOADFILEButtonPushed, true);
            app.LOADFILEButton.Text = 'LOAD FILE';
            app.XDataDropDownLabel = uilabel(app.LeftPanel);
            app.XDataDropDownLabel.Text = 'X Data';
            app.XDataDropDown = uidropdown(app.LeftPanel);
            app.XDataDropDown.Items = {'Select an option'};
            app.XDataDropDown.ValueChangedFcn = createCallbackFcn(app, @XorYDataDropDownValueChanged, true);
            app.XDataDropDown.Value = 'Select an option';
            app.YDataDropDownLabel = uilabel(app.LeftPanel);
            app.YDataDropDownLabel.Text = 'Y Data';
            app.YDataDropDown = uidropdown(app.LeftPanel);
            app.YDataDropDown.Items = {'Select an option'};
            app.YDataDropDown.ValueChangedFcn = createCallbackFcn(app, @XorYDataDropDownValueChanged, true);
            app.YDataDropDown.Value = 'Select an option';
            app.startatthiscommentListBoxLabel = uilabel(app.LeftPanel);
            app.startatthiscommentListBoxLabel.Text = 'start at this comment';
            app.startatthiscommentListBox = uilistbox(app.LeftPanel);
            app.startatthiscommentListBox.Items = {'select a comment'};
            app.startatthiscommentListBox.ValueChangedFcn = createCallbackFcn(app, @commentListBoxValueChanged, true);
            app.startatthiscommentListBox.Value = 'select a comment';
            app.endatthiscommentListBoxLabel = uilabel(app.LeftPanel);
            app.endatthiscommentListBoxLabel.Text = 'end at this comment';
            app.endatthiscommentListBox = uilistbox(app.LeftPanel);
            app.endatthiscommentListBox.Items = {'select a comment'};
            app.endatthiscommentListBox.ValueChangedFcn = createCallbackFcn(app, @commentListBoxValueChanged, true);
            app.endatthiscommentListBox.Value = 'select a comment';
            app.dXstartEditFieldLabel = uilabel(app.LeftPanel);
            app.dXstartEditFieldLabel.Text = 'dX start';
            app.dXstartEditField = uieditfield(app.LeftPanel, 'numeric');
            app.dXstartEditField.ValueChangedFcn = createCallbackFcn(app, @dXstartEditFieldValueChanged, true);
            app.dXendEditFieldLabel = uilabel(app.LeftPanel);
            app.dXendEditFieldLabel.Text = 'dX end';
            app.dXendEditField = uieditfield(app.LeftPanel, 'numeric');
            app.dXendEditField.ValueChangedFcn = createCallbackFcn(app, @dXendEditFieldValueChanged, true);
            app.filterdataCheckBox = uicheckbox(app.LeftPanel);
            app.filterdataCheckBox.ValueChangedFcn = createCallbackFcn(app, @filterdataCheckBoxValueChanged, true);
            app.filterdataCheckBox.Text = 'filter data';
            app.cutofffrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.cutofffrequencyEditFieldLabel.Text = 'cutoff frequency';
            app.cutofffrequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.cutofffrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @cutofffrequencyEditFieldValueChanged, true);
            app.cutofffrequencyEditField.Value = 4.9;
            app.RightPanel = uipanel(app.GridLayout);
            app.selectedfileEditFieldLabel = uilabel(app.RightPanel);
            app.selectedfileEditFieldLabel.Text = 'selected file';
            app.selectedfileEditField = uieditfield(app.RightPanel, 'text');
            app.selectedfileEditField.Editable = 'off';
            app.yUnitsDropDownLabel = uilabel(app.RightPanel);
            app.yUnitsDropDownLabel.Text = 'y Units';
            app.yUnitsDropDown = uidropdown(app.RightPanel);
            app.yUnitsDropDown.Items = {'Volts (V)', 'Millivolts (mV)', 'Microvolts (uV)'};
            app.yUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @yUnitsDropDownValueChanged, true);
            app.yUnitsDropDown.Value = 'Volts (V)';
            app.xUnitsDropDownLabel = uilabel(app.RightPanel);
            app.xUnitsDropDownLabel.Text = 'x Units';
            app.xUnitsDropDown = uidropdown(app.RightPanel);
            app.xUnitsDropDown.Items = {'Seconds (s)', 'Minutes (min)', 'Hours (hr)'};
            app.xUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @xUnitsDropDownValueChanged, true);
            app.xUnitsDropDown.Value = 'Seconds (s)';
            app.AutoscaleYCheckBox = uicheckbox(app.RightPanel);
            app.AutoscaleYCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoscaleYCheckBoxValueChanged, true);
            app.AutoscaleYCheckBox.Text = 'Autoscale Y';
            app.AutoscaleYCheckBox.Value = true;
            app.YMaxEditFieldLabel = uilabel(app.RightPanel);
            app.YMaxEditFieldLabel.Text = 'Y Max';
            app.YMaxEditField = uieditfield(app.RightPanel, 'numeric');
            app.YMaxEditField.ValueChangedFcn = createCallbackFcn(app, @YMaxEditFieldValueChanged, true);
            app.YMaxEditField.Value = 1;
            app.YMinEditFieldLabel = uilabel(app.RightPanel);
            app.YMinEditFieldLabel.Text = 'Y Min';
            app.YMinEditField = uieditfield(app.RightPanel, 'numeric');
            app.YMinEditField.ValueChangedFcn = createCallbackFcn(app, @YMinEditFieldValueChanged, true);
            app.YMinEditField.Value = -1;
            app.ExludevaluesgreaterthanCheckBox = uicheckbox(app.RightPanel);
            app.ExludevaluesgreaterthanCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExludevaluesgreaterthanCheckBoxValueChanged, true);
            app.ExludevaluesgreaterthanCheckBox.Text = 'Exlude values greater than';
            app.YMagnitudeEditFieldLabel = uilabel(app.RightPanel);
            app.YMagnitudeEditFieldLabel.Text = 'Y Magnitude';
            app.YMagnitudeEditField = uieditfield(app.RightPanel, 'numeric');
            app.YMagnitudeEditField.ValueChangedFcn = createCallbackFcn(app, @YMagnitudeEditFieldValueChanged, true);
            app.YMagnitudeEditField.Value = 1;
            app.UIAxes_all = uiaxes(app.RightPanel);
            app.UIAxes_selection = uiaxes(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = data_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end