classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadSettingsMenu                matlab.ui.container.Menu
        SaveSettingsMenu                matlab.ui.container.Menu
        UITable                         matlab.ui.control.Table
        TabGroup                        matlab.ui.container.TabGroup
        DataTab                         matlab.ui.container.Tab
        setthescanratePanel             matlab.ui.container.Panel
        RatescanssEditFieldLabel        matlab.ui.control.Label
        RatescanssEditField             matlab.ui.control.NumericEditField
        RateMinEditFieldLabel           matlab.ui.control.Label
        RateMinEditField                matlab.ui.control.NumericEditField
        RateMaxEditFieldLabel           matlab.ui.control.Label
        RateMaxEditField                matlab.ui.control.NumericEditField
        selecttheNIdevicePanel          matlab.ui.container.Panel
        DeviceDropDownLabel             matlab.ui.control.Label
        DeviceDropDown                  matlab.ui.control.DropDown
        recorddataandmakecommentsPanel  matlab.ui.container.Panel
        RecordButton                    matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        CommentButton                   matlab.ui.control.Button
        LogStatusEditFieldLabel         matlab.ui.control.Label
        LogStatusEditField              matlab.ui.control.EditField
        PlotsettingsTab                 matlab.ui.container.Tab
        xaxisspecifytimerangetodisplayPanel  matlab.ui.container.Panel
        TimewindowEditFieldLabel        matlab.ui.control.Label
        TimewindowEditField             matlab.ui.control.NumericEditField
        xUnitsDropDownLabel             matlab.ui.control.Label
        xUnitsDropDown                  matlab.ui.control.DropDown
        yaxisselectactivechannelstodisplayandsetthevoltagescalePanel  matlab.ui.container.Panel
        activeplotsListBoxLabel         matlab.ui.control.Label
        activeplotsListBox              matlab.ui.control.ListBox
        AutoscaleYSwitchLabel           matlab.ui.control.Label
        AutoscaleYSwitch                matlab.ui.control.Switch
        YmaxEditFieldLabel              matlab.ui.control.Label
        YmaxEditField                   matlab.ui.control.NumericEditField
        YminEditFieldLabel              matlab.ui.control.Label
        YminEditField                   matlab.ui.control.NumericEditField
        yUnitsDropDownLabel             matlab.ui.control.Label
        yUnitsDropDown                  matlab.ui.control.DropDown
        memoryallocationPanel           matlab.ui.container.Panel
        FIFOmaxbuffersizeEditFieldLabel  matlab.ui.control.Label
        FIFOmaxbuffersizeEditField      matlab.ui.control.NumericEditField
        CommenthistoryTab               matlab.ui.container.Tab
        UITable_commentHistory          matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Devices % the output of the daqlist command. This is used for storing all connected device info
        SelectedDevice % the output of the daqlist command, but only the selected device from the dropdown
        initial_table % this is used to store the empty table if the user decides to reset and select a new device
        current_table % this is used to store the state of the DAQ connection table and to identify changes that have been made
        DAQ % creates an app property for the DataAcquisition device interface.
        RateMin % The minimum scanning rate of the daq device
        RateMax % The maximum scanning rate of the daq device
        TimestampsFIFOBuffer  % Timestamps FIFO buffer used for live plot of latest "N" seconds of acquired data
        DataFIFOBuffer        % Data FIFO buffer used for live plot of latest "N" seconds of acquired data
        active_channel_names = cell(1,1) % a cell array of the channel names that are currently active on the DAQ device 
        active_channel_gains % an array of numerical gains that scale the data. Useful if the data is being amplified, for example. 
        yAxis_scalar = 1; % the number used to scale the y-axis display to correct for units (V), (mV), (uV), etc
        yAxis_selectedIDX % a parameter that stores the users selected active channel indices to plot
        xAxis_scalar = 1 % the number used to scale the time array in the figure window
        TriggerTime           % Acquisition start time stored as datetime
        TempFilename          % Temporary binary file name, acquired data is logged to this file during acquisition
        TempFile              % Handle of opened binary file, acquired data is logged to this file during acquisition
        Filename = 'daqdata.mat' % Default MAT file name at app start
        Filepath = pwd        % Default folder for saving the MAT file at app start
        currentViewState = 'configure' % a string character used to set the matlab view state
        isRecordingFlag = 0; % a simple, boolean indicator flag for when to initiate the write to file inside the scans avaiable callback
        commentID = 1; % an app parameter used to keep track of the current comment index number (for cross reference in the metadata)
        queue_comment = 0;  % a boolean flag used by the program to indicate that a new comment should be sychronized with the data
        comment_buffer = cell(1,3) % an app property that stores all user comments in a buffer and the comment time (local)
        Settings % a variable that is used to store all app loaded settings and workplace variables
    end
    
    methods (Access = private)
        
        
        function initDAQtable(app)
            % this function is used to build the table that displays all
            % the available DAQ connections and configurations.
            
            % get the analog input subsystem information. As far as I
            % can tell, the subsystems are always listed in the same
            % order with analog input always first. 
            analog_input_subsystem = app.SelectedDevice.DeviceInfo.Subsystems(1);
            
            % get the maximum and minimum possible scanning rate for this
            % daq subsystem. Set the range to app properties and update the
            % front panel display
            app.RateMin = min(analog_input_subsystem.RateLimit);
            app.RateMax = max(analog_input_subsystem.RateLimit);
            app.RateMinEditField.Value = app.RateMin;
            app.RateMaxEditField.Value = app.RateMax;
            
            % make a list of all the channel names
            channelID_array = cellstr(string(analog_input_subsystem.ChannelNames));
            
            % get the dimension of the channel array
            dimSize_channels = size(channelID_array);
            
            % create an array of booleans that are all false for the
            % user to manually select which channels they want to
            % include in the measurement
            add_array = false(dimSize_channels);
            
            % create an array for the unique/custom channel names when
            % the user decides to add it as an input. Upon creation,
            % they should all be blank.
            name_array = cell(dimSize_channels);
            name_array(:) = {''};
            
            % create an array that will be used to scale the input voltage
            % signal to match the source. For example, if connected to an
            % amplifier, the real signal may be amplified 10x gain over the
            % baseline to reduce noise artifacts. As a result, the user
            % would need to scale the signal back down to get the real
            % signal value. This purpose of this column is to allow the
            % user to specify the amount of gain applied to the input signal.
            signal_gain_array = cell(dimSize_channels);
            signal_gain_array(:) = {'1'};
            
            % create an array of the categorical measurement type
            meas_types_available = analog_input_subsystem.MeasurementTypesAvailable;
            measType_array = cell(dimSize_channels);
            measType_array(:) = meas_types_available(1);
            
            % create an array of the categorical terminal configuration
            terminal_config_available = analog_input_subsystem.TerminalConfigsAvailable;
            terminalConfig_array = cell(dimSize_channels);
            % prefer the differential configuration. However, this option
            % may not be available on every DAQ subsystem out there.
            % Therefore, check to see if the config exists first, otherwise
            % default to the 1st element in the array
            pref_config = {'Differential'};
            if any(strcmp(terminal_config_available,pref_config))
                terminalConfig_array(:) = pref_config;
            else
                terminalConfig_array(:) = terminal_config_available(1);
            end
            
            % create an array of the categorical ranges available with this
            % subsystem
            ranges_available = analog_input_subsystem.RangesAvailable;
            range_array = cell(dimSize_channels);
            % build an array of strings that show the available range
            % options to the user
            numRanges = numel(ranges_available);
            range_items = strings(numRanges,1);
            itemsData = cell(numRanges,1);
            for ii = 1:numRanges
                range = analog_input_subsystem.RangesAvailable(ii);
                range_items(ii) = sprintf('%.2f to %.2f %s', range.Min, range.Max, range.Units);
                itemsData{ii} = [range.Min range.Max];
            end
            range_items = cellstr(range_items); 
            % if the -10 to 10 Volts option is available, then set the
            % default terminal config to this value, otherwise use the
            % first availible configuration
            pref_config = {'-10.00 to 10.00 Volts'};
            if any(strcmp(range_items,pref_config))
                range_array(:) = pref_config;
            else
                range_array(:) = range_items(1);
            end
            
            % create an array of the categorical couplings
            couplings_available = analog_input_subsystem.CouplingsAvailable;
            couplings_array = cell(dimSize_channels);
            % prefer the differential configuration. However, this option
            % may not be available on every DAQ subsystem out there.
            % Therefore, check to see if the config exists first, otherwise
            % default to the 1st element in the array
            pref_config = {'DC'};
            if any(strcmp(couplings_available,pref_config))
                couplings_array(:) = pref_config;
            else
                couplings_array(:) = couplings_available(1);
            end
            
            % add all the data to a table
            T = table(add_array,channelID_array,name_array,signal_gain_array,measType_array,...
                terminalConfig_array,range_array,couplings_array);
            T.measType_array = categorical(T.measType_array,meas_types_available,"Ordinal",true);
            T.terminalConfig_array = categorical(T.terminalConfig_array,cellstr(terminal_config_available),"Ordinal",true);
            T.range_array = categorical(T.range_array,range_items,"Ordinal",true);
            T.couplings_array = categorical(T.couplings_array,cellstr(couplings_available),"Ordinal",true);
            app.UITable.ColumnName = {'Add', 'Channel ID', 'Name','Signal Gain','Measurement Type',...
                'Terminal Configuration', 'Input Range','Coupling'};
            app.UITable.ColumnWidth = {50,'auto','auto','auto','auto','auto','auto','auto'};
            app.UITable.ColumnEditable = [true,false,true,true,true,true,true,true];
            app.UITable.Data = T;
            
            % store the state of the table to the current table property
            app.current_table.Data = app.UITable.Data;
            
            
        end
        
        function findDAQchanges(app)
            % this function is intended to read the DAQ connection table,
            % identify changes that have been made, and make/remove the
            % appropriate connections/changes to the DAQ.
            
            % first identify the state of the table since the last update
            % table0 = state of the table since the last time this function
            % was run.
            % table1 = new desired state of the table
            table0 = app.current_table.Data;
            table1 = app.UITable.Data;
            
            % find the rows that are different between the two tables,
            % return their properties and their row indicies
            [~,rowIDXs] = setdiff(table1,table0,'rows');
            
            % only if actual changes are detected between the desired
            % config and the current config, should we execute the
            % remainder of this script
            if ~isempty(rowIDXs)
                % next step is to find every variable that has changed state
                % and what it has changed to. After identifying these changes,
                % we will need to iteratively make these changes using a
                % switch case. repeat for every changed row
                update = table; % create an empty table
                varnames = table1.Properties.VariableNames; % get var names
                for i = 1:length(rowIDXs)
                    % get the current row index
                    rowIDX = rowIDXs(i);
                    % make a temporary before and after for the row to compare
                    % between
                    r1 = table1(rowIDX,:); % after
                    r0 = table0(rowIDX,:); % before
                    % compare all the variables between these two rows and see
                    % which have changed. save these variables to a
                    % changed_vars array and extract the names (as a function
                    % of their index) from the varnames variable
                    changed_vars = [];
                    for ii = 1:numel(varnames)
                        changed_vars(ii) = ~isequal(r1.(varnames{ii}),r0.(varnames{ii}));
                    end
                    changed_vars = varnames(find(changed_vars));
                    % create a new matrix that contains the row IDX that needs
                    % to change, the variable name that needs to change, and
                    % what the new value should be.
                    for ii = 1:numel(changed_vars)
                        % define the variables in the update table that we want
                        % to store for later. the row index is already created
                        % in this loop.
                        Variable =  {changed_vars{ii}};
                        Value = {r1.(changed_vars{ii})};
                        PreviousValue = {r0.(changed_vars{ii})};
                        % if the values are categorical in nature, matlab
                        % will not concatenate in the same column as non
                        % cateogircal values. So, force the categorical to
                        % a cell, if needed
                        tmp = table(rowIDX,Variable,Value,PreviousValue);
                        if isempty(update)
                            % if the table did not already exist, then we just
                            % store the temporary table value to the update
                            % table
                            update = tmp;
                        else
                            % if the update table already has a change, then we
                            % append the new row to the existing table
                            update = [update; tmp];
                        end
                    end
                end
                % we now have an array that specifies the row, variable, and
                % property that we want to update we are ready to run a script
                % that handles all changes for the DAQ
                updateDAQ(app,update);
            end
            
        end
        
        function updateDAQ(app,update)
            % this function requires an input variable called update.
            % Update is a table function that contains the row name in the
            % app.UITable to update, the column name to update, the value
            % to set the variable to, and the previous value.
            
            % if update table is empty, skip this function
            if isempty(update)
                return
            end
            
            % this function alters the state of the data acquisition
            % object, therefore we need to ensure that we are not reading
            % from the daq while we make these changes
            if app.DAQ.Running
                stop(app.DAQ)
            end
            
            % get the variable names for the columns in the app.UITable
            varnames = app.UITable.Data.Properties.VariableNames;
            
            % check to make sure that the variable names match what we
            % expect when we are extracting and adding properties here.
            % Compare our hard coded values with the current properties of
            % the table. If they do not match, display a warning dialog and
            % skip the remainder of this function
            expected_varnames = {'add_array', 'channelID_array','name_array','signal_gain_array',...
                'measType_array','terminalConfig_array','range_array','couplings_array'};
            if ~all(strcmp(varnames,expected_varnames))
                warning('the property VariableNames for the app.UITable does not match the hardcoded values in the updateDAQ function')
                fprintf('\nVariableNames:\n\t %s\n',string(strjoin(varnames)))
                fprintf('\nHardcoded VariableNames:\n\t %s\n\n',string(strjoin(expected_varnames)))
                return
            end
            
            % ADD OR REMOVE INPUTS ----------------------------------------
            % Find the indicies of the update table that correspond to the
            % column in the UI table called "add_array." "add_array" is the
            % name for the column that indicates adding/removing an input
            % to the daq device. 
            updateIDX = find(strcmp(update.Variable,'add_array'));
            % if any of the detected changes in the UI table require adding
            % or removing an analog input channel, do this first. 
            if ~isempty(updateIDX)
                for i = 1:length(updateIDX)
                    % get the row idx where the "add" property was changed
                    % in the UITable.
                    idx = update.rowIDX(updateIDX(i));
                    % get the current channel properties for that row from
                    % the GUI table.
                    channel_properties = app.UITable.Data(idx,:);
                    ID = char(channel_properties.channelID_array);
                    % check to see if the requested input is already
                    % connected to the DAQ device. if it is, return the row
                    % index. [custom function]
                    channelIDX = getChannelIDX(app,ID);
                    % there are 4 possible conditions for the add/remove
                    % channel function. the user can specify adding or
                    % removing a channel in the UITable, but we need to
                    % make sure that the input does not ALREADY EXIST. The
                    % 4 conditions are (1) user wants to add and the
                    % channel does not alreay exist. (2) user wants to
                    % remove and the channel does exist. (3) user wants to
                    % add but the channel already exists. (4) user wants to
                    % remove but the channel didn't exist in the first
                    % place. 
                    if update.Value{updateIDX(i)} && isempty(channelIDX)
                        % if the user wants to add a new channel and it is
                        % not already an input, add it here
                        disp('adding a new channel')
                        ch = addinput(...
                            app.DAQ,...
                            app.SelectedDevice.DeviceID,...
                            char(channel_properties.channelID_array),...
                            char(channel_properties.measType_array));
                        % now we need to update the GUI with the correct
                        % channel properties
                        channel_properties.channelID_array = {ch.ID};
                        channel_properties.name_array = {ch.Name};
                        channel_properties.measType_array = {ch.MeasurementType};
                        channel_properties.terminalConfig_array = {ch.TerminalConfig};
                        channel_properties.range_array = cellstr(sprintf('%.2f to %.2f %s', ch.Range.Min, ch.Range.Max, ch.Range.Units));
                        channel_properties.couplings_array = {ch.Coupling};
                    elseif ~update.Value{updateIDX(i)} && ~isempty(channelIDX)
                        % if the user wants to remove an input channel and
                        % it does currently exist, remove it here
                        disp('removing an existing channel')
                        removechannel(app.DAQ,channelIDX)
                    elseif update.Value{updateIDX(i)} && ~isempty(channelIDX)
                        % the user wants to add an input but it already
                        % exists. Update the table to reflect the
                        % pre-existing channel properties
                        disp('this channel already exists. updating table properties')
                        % get the connected channel properites
                        ch = app.DAQ.Channels(channelIDX);
                        % now we need to update the GUI with the correct
                        % channel properties
                        channel_properties.channelID_array = {ch.ID};
                        channel_properties.name_array = {ch.Name};
                        channel_properties.measType_array = {ch.MeasurementType};
                        channel_properties.terminalConfig_array = {ch.TerminalConfig};
                        channel_properties.range_array = cellstr(sprintf('%.2f to %.2f %s', ch.Range.Min, ch.Range.Max, ch.Range.Units));
                        channel_properties.couplings_array = {ch.Coupling};
                    elseif ~update.Value{updateIDX(i)} && isempty(channelIDX)
                        disp('cannot delete this channel becuase it is not currently an input')
                    end
                    % update the UITable with the current channel
                    % properties.
                    app.UITable.Data(idx,:) = channel_properties;
                end
                % remove any updates that have been completed up to this
                % point
                update(updateIDX,:) = [];
            end
            
            % PERFORM REMAINING UPDATES -----------------------------------
            % look to see which channel updates remain after adding or
            % removing inputs, if any 
            if ~isempty(update)
                % loop through every remaining update
                [n,~] = size(update);
                for i = 1:n
                    % grab the update in the top index. Not the "i"th 
                    % update. Update variable acts as a queue. Once the
                    % action is performed, it should be deleted from the
                    % queue
                    this_update = update(1,:);
                    % remove this update from the queue
                    update(1,:) = [];
                    % grab the channel ID for the update that we want to
                    % perform using the current update variable name and
                    % row index. The output is a character array to the
                    % variable called ID. This ID is passed to a custom
                    % function to get the connected channel's index.
                    ID = app.UITable.Data.channelID_array{this_update.rowIDX};
                    % find the index of the input channel that we want to
                    % modify [custom function]
                    channelIDX = getChannelIDX(app,ID);
                    % only allow the user to make changes to connected
                    % channels. Therefore this entire section of code
                    % should undo any changes made to the app.UITable if
                    % the user modifies an unconnected channel
                    if ~isempty(channelIDX)
                        % we now need to determine the property that we want to
                        % change with a switch case
                        switch this_update.Variable{:}
                            case 'name_array'
                                disp('changing channel name')
                                Property = 'Name';
                                Value = char(this_update.Value{:});
                                % enforce the change in the UITable
                                app.UITable.Data.(this_update.Variable{:}){this_update.rowIDX} = Value; 
                            case 'signal_gain_array'
                                % non-native NI property. This should not
                                % be passed ot the set(app.DAQ) function.
                                disp('changing signal gain')
                                Property = 'Gain';
                                Value = char(this_update.Value{:});
                                % enforce the change in the UITable
                                app.UITable.Data.(this_update.Variable{:}){this_update.rowIDX} = Value; 
                            case 'measType_array'
                                disp('changing meas type')
                                Property = 'MeasurementType';
                                Value = char(this_update.Value{:});
                                % enforce the change in the UITable
                                app.UITable.Data.(this_update.Variable{:})(this_update.rowIDX) = {Value}; 
                            case 'terminalConfig_array'
                                disp('changing terminal config')
                                Property = 'TerminalConfig';
                                Value = char(this_update.Value{:});
                                % enforce the change in the UITable
                                app.UITable.Data.(this_update.Variable{:})(this_update.rowIDX) = {Value}; 
                            case 'range_array'
                                % range is unique becuase the values are
                                % packaged inside a string. So, we need to
                                % unpack the string to get the desired
                                % range values
                                disp('changing range')
                                Property = 'Range';
                                % use a temporary variable to extracted the
                                % desired values from the range string
                                tmp = char(this_update.Value{:});
                                % enforce the change in the UITable
                                app.UITable.Data.(this_update.Variable{:})(this_update.rowIDX) = {tmp}; 
                                % remove the word ''to'' between the two
                                % values of interest
                                toIDX = strfind(tmp,'to');
                                toIDX = toIDX:toIDX+1;
                                tmp(toIDX) = [];
                                % Value should be 1x2 [range.Min range.Max]
                                % so we need to sort the extracted values
                                Value = sort(sscanf(tmp,'%f'))';
                            case 'couplings_array'
                                disp('changing coupling')
                                Property = 'Coupling';
                                Value = char(this_update.Value{:});
                                % enforce the change in the UITable
                                app.UITable.Data.(this_update.Variable{:})(this_update.rowIDX) = {Value}; 
                            otherwise
                                uialert(app.UIFigure,'unknown property update in UITable','property update error');
                        end
                        % If the property that the user wants to modify is
                        % a native app.DAQ property (i.e., needs to
                        % communicate with the NI drivers) then use the try
                        % and catch system to upate. If it is not a native
                        % NI property (example: the add_array is NOT a
                        % native array but rather a simple boolean), then
                        % just update the value
                        native_NI_properties = {'Name', 'MeasurementType',...
                            'TerminalConfig', 'Range', 'Coupling'};
                        if any(strcmp(Property,native_NI_properties))
                            try
                                set(app.DAQ.Channels(channelIDX), Property, Value)
                            catch exception
                                uialert(app.UIFigure, exception.message, 'Channel property error');
                                % reset UITable to init value if the change
                                % failed
                                app.UITable.Data.(this_update.Variable{:}){this_update.rowIDX} = ...
                                    char(this_update.PreviousValue{:}); 
                            end
                        end
                        
                    else
                        % if the user modified a channel that is not
                        % connected to the daq, reset the property to the
                        % original value
                        disp('can only modify connected channels, resetting changes')
                        app.UITable.Data(this_update.rowIDX,:) = ...
                            app.current_table.Data(this_update.rowIDX,:);
                    end
                end
            end
            
            % REFRESH FRONT PANEL -----------------------------------------
            % Make sure shown channel property values are not stale, as 
            % some property updates can trigger changes in other sections.
            % Update UI with current channel property values from data 
            % acquisition object
            updateScanningRateLimits(app); % custom function
            
            % UPDATE CURRENT TABLE PROPERTY -------------------------------
            % update the current table state in the app property.
            % this should be the last step before starting the DAQ
            % background recording
            app.current_table.Data = app.UITable.Data;
            
            % UPDATE THE CURRENTLY ACTIVE CHANNEL NAMES -------------------
            % we need to keep track of the names of the channels that are
            % currently active as well as their corresponding order. This
            % has implications into how the data is saved and how the data
            % is scaled.
            if ~isempty(app.DAQ.Channels)
                app.active_channel_names = {app.DAQ.Channels(:).Name};
                app.activeplotsListBox.Items = app.active_channel_names;
            else
                % if no channels are currently connected, reset to an empty
                % cell array
                app.active_channel_names = cell(1,1);
                app.activeplotsListBox.Items = {''};
            end
            % now run a function that will update the channel gains
            % properties
            getChannelGains(app);
            
            % UPDATE THE DROP DOWN SELECTIONS -----------------------------
            % ensure that the GUI is up-to-date with the user defined
            % selections for the active plot to display in the front
            % window. It is possible that the selections become stale after
            % refreshing the options in the drop down list. Specifically,
            % if the user adds then later removes a channel. Their
            % selection may transfer to a new plot but not be reflected on
            % the actual figure unless it is refreshed
            getActivePlotIDXs(app);
            
            % START DAQ ---------------------------------------------------
            startDAQ(app); % custom function
        end
        
        function channelIDX = getChannelIDX(app,ID)
            % this function compares a specific row from the GUI table with
            % input channels on the DAQ device (if it has been created)
            % to see if the channel already exists. If it does, then it
            % returns the index of the channel, if it does not it should
            % return an empty variable
            channelIDX = [];
            if ~isempty(app.DAQ.Channels)
%                 disp('app.DAQ.Channels is not empty')
                % if a DAQ channel already exists, we need to compare our
                % current channel ID with the pre-existing channels. If we
                % find a match, return that channelIDX
                for i = 1:numel(app.DAQ.Channels)
                    existing_ID = app.DAQ.Channels(i).ID;
                    % compare the existing ID with the input ID
                    if strcmp(existing_ID,ID)
                        channelIDX = i;
                    end
                end
            else
%                 disp('app.DAQ.Channels is empty')
            end
        end
        
        
        function updateScanningRateLimits(app)
            % use this function to update the rate limit panel for the GUI
            % and to ensure that the selected scanning rate is within the
            % bounds, if it is not, fix it for the user automatically
            if isempty(app.DAQ)
                % do not run this function if a device has not been
                % selected yet.
                return
            end
            
            % if the DAQ was running, stop it temporarily to make changes,
            % then resume afterwards
            isRunning = app.DAQ.Running;
            if isRunning
                stop(app.DAQ);
            end
            
            % get the current DAQ rate limits
            rateLimit = app.DAQ.RateLimit;
            
            % The rate limit variable could be empty if no analog inputs
            % are connected to the device, in that case, use the default
            % values for rate min and rate max stored in the corresponding
            % property values. Otherwise, use the current rate limits from
            % the connected daq
            if isempty(rateLimit)
                app.RateMaxEditField.Value = app.RateMax;
                app.RateMinEditField.Value = app.RateMin;
                app.RatescanssEditField.Limits = [app.RateMin, app.RateMax];
            else
                app.RateMaxEditField.Value = max(rateLimit);
                app.RateMinEditField.Value = min(rateLimit);
                app.RatescanssEditField.Limits = rateLimit;
            end
            
            % check to make sure that the user selected rate limit is
            % within the bounds, if it is not, then force the rate to be
            % within the new bounds and alert the user
            rate = app.RatescanssEditField.Value;
            isAboveMax = rate>app.RateMaxEditField.Value;
            isBelowMin = rate<app.RateMinEditField.Value;
            rangeExists = app.RateMaxEditField.Value > app.RateMinEditField.Value;
            if rangeExists && (isAboveMax || isBelowMin)
                if isAboveMax
                    rate = app.RateMaxEditField.Value;
                    message = sprintf('The selected scanning rate was too high.\nScanning rate reduced to: %1.2g (scans/s)',rate);
                elseif isBelowMin
                    rate = app.RateMinEditField.Value;
                    message = sprintf('The selected scanning rate was too low.\nScanning rate increased to: %1.2g (scans/s)',rate);
                end
                uialert(app.UIFigure,message,'Data scanning rate limit warning',...
                    'Icon','warning')
                % update the front panel object for the scan rate to be
                % within the new range
                app.RatescanssEditField.Value = rate;
            elseif ~rangeExists
                uialert(app.UIFigure,'Data scan rate property "Range Max" is not greater than "Range Min"','Scanning rate range error');
                % if a range does not exist, do not do anything else in
                % this code. 
                return
            end
            
            % update the DAQ device scanning rate to the new value
            app.DAQ.Rate = rate;
            
            % set the limits for the rate edit field here
%             app.RatescanssEditField.Limits = [app.RateMinEditField.Value, app.RateMaxEditField.Value];
            
            % update the range for the time window in the front panel
            % Minimum time window shows 2 samples
            % Maximum time window corresponds to the maximum specified FIFO buffer size
            minTimeWindow = 1/rate;
            maxTimeWindow = app.FIFOmaxbuffersizeEditField.Value / rate;
            app.TimewindowEditField.Limits = [minTimeWindow, maxTimeWindow];
            
            % restart the DAQ if it was running before this function was
            % called
            if isRunning
                startDAQ(app); % custom function
            end
        end
        
        function scansAvailable_Callback(app,src,~)
            %scansAvailable_Callback Executes on DAQ object ScansAvailable event
            %  This callback function gets executed periodically as more data is acquired.
            %  For a smooth live plot update, it stores the latest N seconds
            %  (specified time window) of acquired data and relative timestamps in FIFO
            %  buffers. A live plot is updated with the data in the FIFO buffer.
            %  If data logging option is selected in the UI, it also writes data to a
            %  binary file.
            
            if ~isvalid(app)
                return
            end
            
            [data,timestamps,triggertime] = read(src, src.ScansAvailableFcnCount, 'OutputFormat','Matrix');
            
            % apply the element by element channel gain correction to the data
            data = data./app.active_channel_gains;
            
            % get the dimension for the timestamps buffer
            dim_data = size(timestamps);
            
            % make an array of 0s, matching the dimension of the timestamps buffer
            comment_buffer_local = zeros(dim_data);
            
            % if a comment has been queued, replace the first element in
            % the buffer (assuming either 1xn or nx1 configuration) with
            % the current comment ID
            if app.queue_comment
                % reset the comment queue since it is being added right now
                app.queue_comment = 0;
                
                % add the comment ID to the beginning of the buffer if size
                % is > 1x1
                comment_buffer_local(1) = app.commentID;
                
                % increment the comment ID after inserting the comment into
                % the data
                app.commentID = app.commentID + 1;
            end
            
            % append the comment buffer to the end of the data buffer
            data = [data comment_buffer_local];
            
            % store the data to the temporary file only if the user has
            % enabled the record button and the 
            if app.isRecordingFlag 
                % If Log data to file switch is on
                latestdata = [timestamps, data]' ;
                fwrite(app.TempFile, latestdata, 'double');
                if timestamps(1)==0
                    app.TriggerTime = triggertime;
                end
            end
            
            % Store continuous acquisition data in FIFO data buffers
            buffersize = round(app.DAQ.Rate * app.TimewindowEditField.Value./app.xAxis_scalar) + 1;
            app.TimestampsFIFOBuffer = storeDataInFIFO(app, app.TimestampsFIFOBuffer, buffersize, timestamps);
            app.DataFIFOBuffer = storeDataInFIFO(app, app.DataFIFOBuffer, buffersize, data); 
            
            % Update plot data
            plot(app.UIAxes,...
                app.TimestampsFIFOBuffer.*app.xAxis_scalar,...
                app.DataFIFOBuffer(:,app.yAxis_selectedIDX).*app.yAxis_scalar,...
                'LineWidth',2)
            if numel(app.TimestampsFIFOBuffer) > 1
                xlim(app.UIAxes, [app.TimestampsFIFOBuffer(1).*app.xAxis_scalar, app.TimestampsFIFOBuffer(end).*app.xAxis_scalar])
            end
            
        end
        
        function data = storeDataInFIFO(~, data, buffersize, datablock)
            %storeDataInFIFO Store continuous acquisition data in a FIFO data buffer
            %  Storing data in a finite-size FIFO buffer is used to plot the latest "N" seconds of acquired data for
            %  a smooth live plot update and without continuously increasing memory use.
            %  The most recently acquired data (datablock) is added to the buffer and if the amount of data in the
            %  buffer exceeds the specified buffer size (buffersize) the oldest data is discarded to cap the size of
            %  the data in the buffer to buffersize.
            %  input data is the existing data buffer (column vector Nx1).
            %  buffersize is the desired buffer size (maximum number of rows in data buffer) and can be changed.
            %  datablock is a new data block to be added to the buffer (column vector Kx1).
            %  output data is the updated data buffer (column vector Mx1).
        
            % If the data size is greater than the buffer size, keep only the
            % the latest "buffer size" worth of data
            % This can occur if the buffer size is changed to a lower value during acquisition
            if size(data,1) > buffersize
                data = data(end-buffersize+1:end,:);
            end
            
            if size(datablock,1) < buffersize
                % Data block size (number of rows) is smaller than the buffer size
                if size(data,1) == buffersize
                    % Current data size is already equal to buffer size.
                    % Discard older data and append new data block,
                    % and keep data size equal to buffer size.
                    shiftPosition = size(datablock,1);
                    data = circshift(data,-shiftPosition);
                    data(end-shiftPosition+1:end,:) = datablock;
                elseif (size(data,1) < buffersize) && (size(data,1)+size(datablock,1) > buffersize)
                    % Current data size is less than buffer size and appending the new
                    % data block results in a size greater than the buffer size.
                    data = [data; datablock];
                    shiftPosition = size(data,1) - buffersize;
                    data = circshift(data,-shiftPosition);
                    data(buffersize+1:end, :) = [];
                else
                    % Current data size is less than buffer size and appending the new
                    % data block results in a size smaller than or equal to the buffer size.
                    % (if (size(data,1) < buffersize) && (size(data,1)+size(datablock,1) <= buffersize))
                    data = [data; datablock];
                end
            else
                % Data block size (number of rows) is larger than or equal to buffer size
                data = datablock(end-buffersize+1:end,:);
            end
            
        end
        
        function startDAQ(app)
            % this function performs checks and attempts to start the data
            % acqusition object, if it exists. In addition, it clears the
            % FIFO buffers to avoid dimension mismatches if adding or
            % removing channels
            if ~isempty(app.DAQ)
                % if an input channel exists, start continuous background data
                % acqusition
                if ~isempty(app.DAQ.Channels)
                    app.TimestampsFIFOBuffer = [];
                    app.DataFIFOBuffer = [];
                    try
                        start(app.DAQ,'continuous');
                    catch exception
                        % In case of error show it and revert the change
                        uialert(app.UIFigure, exception.message, 'Start DAQ error');   
                    end
                end
            end
            end
        
            function getChannelGains(app)
                % this function cross references the names for active
                % channels on the DAQ with the desired signal gain and
                % saves the gains to an app property
                app.active_channel_gains = [];
                table_channel_gains = app.UITable.Data.signal_gain_array;
                table_channelIDs = app.UITable.Data.channelID_array;
                
                % check to see if the programmer or user has accidentally
                % changed the name of the signal gain column in the table
                % property. If so, return a warning and skip the rest of this
                % code
                if isempty(table_channel_gains) || isempty(table_channelIDs)
                    warning('signal_gain_array or channelID_array does not match the name of any app.UITable column names!\n')
                    return
                end
                
                % if there are any connected channels, then proceed with the
                % function, if not, do nothing and finish.
                if ~isempty(app.DAQ.Channels)
                    % for each channel, we need to build an array of matching 
                    % signal gains from the app.UITable. Thus, we need to
                    % match the IDX in the 
                    for i = 1:numel(app.DAQ.Channels)
                        ID = app.DAQ.Channels(i).ID;
                        % compare the ID for this channel with the input
                        % app.UITable IDs
                        for ii = 1:numel(table_channelIDs) 
                            if strcmp(ID,table_channelIDs{ii})       
                                app.active_channel_gains = [app.active_channel_gains str2double(table_channel_gains{ii})];
                            end
                        end
                    end
                else
    %                 disp('app.DAQ.Channels is empty')
                end
            end
        
        function setAxisScales(app)        
            % extract the name for the selected units from the drop down
            % box for the y-axis
            selected_Yunits = app.yUnitsDropDown.Value;
            switch selected_Yunits
                case 'Volts (V)'
                    % if the selected option is merely volts, the scalar is
                    % 1:1
                    app.yAxis_scalar = 1;
                    app.UIAxes.YLabel.String = 'Volts (uV)';
                case 'Millivolts (mV)'
                    app.yAxis_scalar = 1000;
                    app.UIAxes.YLabel.String = 'Millivolts (mV)';
                case 'Microvolts (uV)'
                    app.yAxis_scalar = 1e6;
                    app.UIAxes.YLabel.String = 'Microvolts (uV)';
                otherwise
                    % if the selection is not from the list above, send a
                    % warning to the front panel
                    warning('the selected y-axis scale name does not match the items in the swtich case called setAxisScales')
            end
            
            % extract the name for the selected units from the drop down
            % box for the x-axis
            selected_Xunits = app.xUnitsDropDown.Value;
            switch selected_Xunits
                case 'Seconds (s)'
                    % if the selected option is merely seconds, the scalar is
                    % 1:1
                    app.xAxis_scalar = 1;
                    app.UIAxes.XLabel.String = 'Time (s)';
                case 'Minutes (min)'
                    app.xAxis_scalar = 1/60;
                    app.UIAxes.XLabel.String = 'Time (min)';
                otherwise
                    % if the selection is not from the list above, send a
                    % warning to the front panel
                    warning('the selected x-axis scale name does not match the items in the swtich case called setAxisScales')
            end
        end
        
        function getActivePlotIDXs(app)
            % this function finds the index of the app channels that match
            % the names selected in the list box in the GUI. If no
            % selection is made, but channels are activve, automatically
            % add the first selection.
            app.yAxis_selectedIDX = [];
            
            % create custom variables for easier readability
            user_selections = app.activeplotsListBox.Value;
            ordered_options = app.activeplotsListBox.Items;
            
            if ~isempty(user_selections)
%                 disp('you have made a selection!')
                % for every item in the active channel options, determine
                % if the user has selected it. If they have, add the index
                % to the app property
                for i = 1:numel(ordered_options)
                    if any(strcmp(ordered_options{i},user_selections))
                        app.yAxis_selectedIDX = [app.yAxis_selectedIDX i];
                    end
                end
            else
%                 disp('you have not made a selection!')
                app.yAxis_selectedIDX = 1;
                app.activeplotsListBox.Value = app.activeplotsListBox.Items(1);
            end
        end
        
        function setAppViewState(app)
            %setAppViewState Sets the app in a new state and enables/disables corresponding components
            %  state can be 'configuration', 'acquisition', or 'filesave'
        
            switch app.currentViewState                
                case 'configuration'
                    app.DeviceDropDown.Enable = 'on';
                    app.RatescanssEditField.Enable = 'on';
                    app.TimewindowEditField.Enable = 'on';
                    app.FIFOmaxbuffersizeEditField.Enable ='on';
                    app.CommentButton.Enable = 'off';
                    app.StopButton.Enable = 'off';
                    app.UITable.Enable = 'on';
                    app.LoadSettingsMenu.Enable = 'on';
                    app.SaveSettingsMenu.Enable = 'on';
                    
                    % only enable the record button if at least one channel
                    % is active. since the app.DAQ property may not be
                    % selected yet. we can use a proxy app value that is
                    % only populated as channels are added to the app.DAQ
                    % property. In this case, we use the active plots list.
                    % If no options are listed, then we have not selected
                    % a device and added a channel yet. 
                    if ~isempty(app.activeplotsListBox.Value)
                        app.RecordButton.Enable = 'on';
                    else
                        app.RecordButton.Enable = 'off';
                    end
                    
                case 'acquisition'
                    app.DeviceDropDown.Enable = 'off';
                    app.RatescanssEditField.Enable = 'off';
                    app.TimewindowEditField.Enable = 'on';
                    app.FIFOmaxbuffersizeEditField.Enable ='off';
                    app.RecordButton.Enable = 'off';
                    app.CommentButton.Enable = 'on';
                    app.StopButton.Enable = 'on';
                    app.UITable.Enable = 'off';
                    app.LoadSettingsMenu.Enable = 'off';
                    app.SaveSettingsMenu.Enable = 'off';
%                     app.RateEdit.Enable = 'off';
%                     app.RateSlider.Enable = 'off';
%                     app.DeviceDropDown.Enable = 'off';
%                     app.ChannelDropDown.Enable = 'off';
%                     app.MeasurementTypeDropDown.Enable = 'off';
%                     app.RangeDropDown.Enable = 'off';
%                     app.TerminalConfigDropDown.Enable = 'off';
%                     app.CouplingDropDown.Enable = 'off';
%                     app.StartButton.Enable = 'off';
%                     app.LogdatatofileSwitch.Enable = 'off';
%                     app.ExcitationSourceDropDown.Enable = 'off';
%                     app.StopButton.Enable = 'on';
%                     app.TimewindowEditField.Enable = 'on';
%                     updateLogdatatofileSwitchComponents(app)

                case 'filesave'
                    app.DeviceDropDown.Enable = 'off';
                    app.RatescanssEditField.Enable = 'off';
                    app.TimewindowEditField.Enable = 'off';
                    app.FIFOmaxbuffersizeEditField.Enable ='off';
                    app.RecordButton.Enable = 'off';
                    app.CommentButton.Enable = 'off';
                    app.StopButton.Enable = 'off';
                    app.UITable.Enable = 'off';
                    app.LoadSettingsMenu.Enable = 'off';
                    app.SaveSettingsMenu.Enable = 'off';
%                     app.RateEdit.Enable = 'off';
%                     app.RateSlider.Enable = 'off';
%                     app.DeviceDropDown.Enable = 'off';
%                     app.ChannelDropDown.Enable = 'off';
%                     app.MeasurementTypeDropDown.Enable = 'off';
%                     app.RangeDropDown.Enable = 'off';
%                     app.TerminalConfigDropDown.Enable = 'off';
%                     app.CouplingDropDown.Enable = 'off';
%                     app.StartButton.Enable = 'off';
%                     app.LogdatatofileSwitch.Enable = 'off';
%                     app.ExcitationSourceDropDown.Enable = 'off';
%                     app.StopButton.Enable = 'off';
%                     app.TimewindowEditField.Enable = 'on';
%                     updateLogdatatofileSwitchComponents(app)   
            end
            
        end


        function binFile2MAT(~, filenameIn, filenameOut, numColumns, metadata)
        %BINFILE2MAT Loads 2-D array of doubles from binary file and saves data to MAT file
        % Processes all data in binary file (filenameIn) and saves it to a MAT file without loading
        % all data to memory.
        % If output MAT file (filenameOut) already exists, data is overwritten (not appended).
        % Input binary file is a matrix of doubles with numRows x numColumns
        % MAT file (filenameOut) is a MAT file with the following variables
        %   timestamps = a column vector ,  the first column in the data from binary file
        %   data = a 2-D array of doubles, includes 2nd-last columns in the data from binary file
        %   metatada = a structure, which is provided as input argument, used to provide additional
        %              data information
        %
            
            % If filenameIn does not exist, error out
            if ~exist(filenameIn, 'file')
                error('Input binary file ''%s'' not found. Specify a different file name.', filenameIn);
            end
            
            % If output MAT file already exists, delete it
            if exist(filenameOut, 'file')
                delete(filenameOut)
            end
            
            % Determine number of rows in the binary file
            % Expecting the number of bytes in the file to be 8*numRows*numColumns
            fileInfo = dir(filenameIn);
            numRows = floor(fileInfo.bytes/(8*double(numColumns)));
            
            % if no data was written to the file, we do not need to proceed
            % with the rest of this function
            if numRows<=0
                return
            end
            
            
            % Create matfile object to save data loaded from binary file
            matObj = matfile(filenameOut);
            matObj.Properties.Writable = true;
            
            % Initialize MAT file
            matObj.timestamps(numRows,1) = 0;
            matObj.data(numRows,numColumns-1) = 0;
            
            % Open input binary file
            fid = fopen(filenameIn,'r');
            
            % Specify how many rows to process(load and save) at a time
            numRowsPerChunk = 10E+6;
            
            % Keeps track of how many rows have been processed so far
            ii = 0;
            
            while(ii < numRows)
                
                % chunkSize = how many rows to process in this iteration
                % If it's the last iteration, it's possible the number of rows left to
                % process is different from the specified numRowsPerChunk
                chunkSize = min(numRowsPerChunk, numRows-ii);
                
                data = fread(fid, [numColumns,chunkSize], 'double');
                
                matObj.timestamps((ii+1):(ii+chunkSize), 1) = data(1,:)';
                matObj.data((ii+1):(ii+chunkSize), :) = data(2:end,:)';
                
                ii = ii + chunkSize;
            end
            
            fclose(fid);
            
            % Save provided metadata to MAT file
            matObj.metadata = metadata;
            
        end
        
        function updateAutoscaleYSwitchComponents(app)
        %updateAutoscaleYSwitchComponents Updates UI components related to y-axis autoscale
        
            value = app.AutoscaleYSwitch.Value;
            switch value
                case 'Off'
                    app.YminEditField.Enable = 'on';
                    app.YmaxEditField.Enable = 'on';
                    YmaxminValueChanged(app, []);
                case 'On'
                    app.YminEditField.Enable = 'off';
                    app.YmaxEditField.Enable = 'off';
                    app.UIAxes.YLimMode = 'auto';
            end
            
        end
        
        function loadSettings(app)
            % load previously stored settings to the matlab environment
            S = app.Settings;
            
            % first, we should attempt to connect to the previously
            % selected DAQ device
            devIDX = find(strcmp(S.props,'DeviceDropDown'));
            
            % get the name of the cell that was selected previously in the
            % DeviceDropDown selection.
            devName = S.items{devIDX}(S.values{devIDX});
            
            % see if that name exists in our generated list during startup
            logical_array = strcmp(app.DeviceDropDown.Items,devName);
            
            % if the query found a match, then continue, however, skip the
            % rest of this function and warn the user that it did not find
            % a matching device
            if ~any(logical_array)
                message = strcat(devName,' is not detected by matlab!');
                uialert(app.UIFigure, message, 'DAQ device not detected');
                return
            else
                % get the index of the matching device name in our drop
                % down
                selected_deviceIDX = find(logical_array);
                
                % get the current value from the drop down menu
                event.PreviousValue = app.DeviceDropDown.Value;
                
                % set the dropdown to the desired value
                app.DeviceDropDown.Value = selected_deviceIDX;
                
                % run the device drop down callback
                DeviceDropDownValueChanged(app, event)
            end
            
            % hard code the properties that should be loaded and run the
            % appropriate callbacks, if necessary
            
            setAppPropertyStates(app,S,'RatescanssEditField')
            RatescanssEditFieldValueChanged(app)
            
            setAppPropertyStates(app,S,'TimewindowEditField')
            % no callback for time window
            
            setAppPropertyStates(app,S,'xUnitsDropDown')
            xUnitsDropDownValueChanged(app)
            
            setAppPropertyStates(app,S,'AutoscaleYSwitch')
            AutoscaleYSwitchValueChanged(app)
            
            setAppPropertyStates(app,S,'YminEditField')
            setAppPropertyStates(app,S,'YmaxEditField')
            YmaxminValueChanged(app) % shared callback
            
            setAppPropertyStates(app,S,'yUnitsDropDown')
            yUnitsDropDownValueChanged(app)
            
            setAppPropertyStates(app,S,'FIFOmaxbuffersizeEditField')
            % no callback for FIFO
            
            setAppPropertyStates(app,S,'UITable')
            UITableCellEdit(app)
%             
%             % define a white list of variables that are allowed to be
%             % loaded
%             whitelist = {'RatescanssEditField','TimewindowEditField'};
%             
%             % for every property in the app, check to see if it is allowed
%             % to be edited by the white list, if so, set values and other
%             % properties
%             for i = 1:length(S.props)
%                 % assign prop name to variable
%                 propName = S.props{i};
%                 % check if in whitelist
%                 if any(strcmp(propName,whitelist))
%                     % get the property from the current app
%                     property = app.(propName);
%                     if isprop(property, 'Value')
%                         app.(propName).Value = S.values{i};
%                     end
%                     if isprop(property, 'Enable')
%                         app.(S.props{i}).Enable = S.enabled{i};
%                     end
%                 end
%                 
%             end
        end
        
        function setAppPropertyStates(app,SettingsMAT,propName)
            % this function automatically sets the properties of a
            % spcecific variable for a given, loaded file with known file
            % structure
            S = SettingsMAT;
            
            % get property idx
            logical_array = strcmp(propName,S.props);
            if any(logical_array)
                % get the property from the current app
                property = app.(propName);
                % extract the matching property values from the settings
                % mat file structure
                i = find(logical_array);
                if isprop(property, 'Value')
                    app.(propName).Value = S.values{i};
                end
                if isprop(property, 'Enable')
                    app.(S.props{i}).Enable = S.enabled{i};
                end
                if isprop(property, 'Items')
                    app.(S.props{i}).Items = S.items{i};
                end
                if isprop(property, 'Data')
                    app.(S.props{i}).Data = S.data{i};
                end
                
            else
                message = strcat('the app property name "',propName,'" does not exist!');
                uialert(app.UIFigure,message,'Missing property error')
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % grab the information for the 'empty table' this is used to
            % reset the table back to its original state if the user resets
            % the table
            app.initial_table.Data = app.UITable.Data;
            
            % Get connected devices that have the supported subsystem and measurement types
            devices = daqlist("ni");
            
            % set the app.DAQ property to an empty variable
            app.DAQ = [];
            
            % we will access this list of devices and device information in
            % other parts of the app, so we will assign it to a device
            % property
            app.Devices = devices;
            
            % Populate the device drop down list with cell array of composite device names (ID + model)
            % First element is "Select a device"
            deviceDescriptions = cellstr(devices.DeviceID + " [" + devices.Model + "]");
            app.DeviceDropDown.Items = ['Select a device'; deviceDescriptions];
            
            % initialize the y-axis drop down item to show that no inputs
            % have been connected
            app.activeplotsListBox.Items = {' '};
            
            % Assign dropdown ItemsData to correspond to device index + 1
            % (first item is not a device)
            app.DeviceDropDown.ItemsData = 1:numel(app.DeviceDropDown.Items)+1;
            
            % Turn off axes toolbar and data tips for live plot axes
            app.UIAxes.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.UIAxes);
            
            % set the axis scale to match the drop down option in the
            % y-axis and x-axis menus
            setAxisScales(app);
            
            % update the autoscale properties such as axis limits and
            % enable/disable the edit boxes
            updateAutoscaleYSwitchComponents(app);
            
            % set the front panel to the configuration state
            app.currentViewState = 'configuration';
            setAppViewState(app)
        end

        % Value changed function: DeviceDropDown
        function DeviceDropDownValueChanged(app, event)
            value = app.DeviceDropDown.Value;
            previous_value = event.PreviousValue;
            
            % check to make sure that the device is returning a logical
            % value for the drop down selection
            if ~isempty(value)
                
                % if the user is changing from any state other than the
                % "select a device option" the user should have a
                % confirmation dialog box that confirms the changes before
                % actually doing anything. Select a device option is always
                % the first element in the list. 
                if previous_value ~= 1
                    question = 'Are you sure you would like to disconnect from the current device?';
                    title = 'Confirm Change Device';
                    selection = uiconfirm(app.UIFigure,question,title,...
                        'Icon','warning');  
                    make_change = strcmp(selection,'OK');
                else
                    make_change = true;
                end
                
                % after the user has interacted with the second menu
                % option, perform the desired action
                if make_change
                    if value == 1
                        % if the drop down state "select a device" is selected,
                        % we should stop any current processes on the
                        % connected DAQ, delete it, and reset the table in
                        % the GUI.
                        
                        % stop any data acqusition that may be occuring
                        stop(app.DAQ)
                        delete(app.DAQ)
                        app.DAQ = [];
                        
                        % reset the selected device data
                        app.SelectedDevice = [];
                        
                        % reset the front GUI table
                        app.UITable.Data = app.initial_table.Data; 
                    else
                        % determine what the selected device's index is. Recall
                        % that the first element in the array is the 'select
                        % device' option. Therefore, we neecd to remove 1 from the
                        % index to match the device list.
                        devIDX = value-1;
                        app.SelectedDevice = app.Devices(devIDX,:);
                        
                        % build a Data acquisition NI device
                        app.DAQ = daq("ni");
                        
                        % configure the scans available callback function
                        app.DAQ.ScansAvailableFcn = @(src,event) scansAvailable_Callback(app, src, event);
                    
                        % run the custom function to build the table
                        initDAQtable(app)
                    end
                else
                    % if the user cancels the action, reset the drop
                    % down to the previous value
                    app.DeviceDropDown.Value = previous_value;
                end
                
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            %indices = event.Indices; % 1x2 array containing row and column indicies of the cell the user edited
            %newData = event.NewData; % user-entered value
            
            % run the update DAQ function if the user makes any changes in
            % the table
            findDAQchanges(app)
            
            % refresh the front panel to prevent stale state
            setAppViewState(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % if the user wants to shut down the app, we need to first stop
            % any ongoing processes and clear up the memory for a new
            % connection to a new daq if the app is opened again.
            
            % if the DAQ object is acquiriring data, we need to have a
            % function that checks, stops acquisition if necessary, and
            % saves any unprocessed data. After saving the data, we need to
            % delete the app.
%             if isrecording?????
%                 stop(app.DAQ)
%                 save data % this is pseudocode
%             end
            
            delete(app)
            
        end

        % Value changed function: RatescanssEditField
        function RatescanssEditFieldValueChanged(app, event)
%             value = app.RatescanssEditField.Value;
            % make sure the front panel scanning rate options are within
            % the correct bounds and update the scanning rate for the daq
            updateScanningRateLimits(app);
            
        end

        % Value changed function: yUnitsDropDown
        function yUnitsDropDownValueChanged(app, event)
            % call the custom function that updates the necessary titles
            % and scalar values for the rest of the app 
            setAxisScales(app);
            
            % update the y-axis if autoscale is enabled
            updateAutoscaleYSwitchComponents(app);
        end

        % Value changed function: activeplotsListBox
        function activeplotsListBoxValueChanged(app, event)
            % any time the user changes the selections in the list box,
            % refresh the active plot indicies
            getActivePlotIDXs(app);
        end

        % Value changed function: xUnitsDropDown
        function xUnitsDropDownValueChanged(app, event)
            % call the custom function that updates the necessary titles
            % and scalar values for the rest of the app 
            setAxisScales(app);
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            % update the display state of the gui to protect from misclicks
            app.currentViewState = 'acquisition';
            setAppViewState(app)
            
            % stop the DAQ acquistion, if it was already running
            stop(app.DAQ)
            
            % Create and open temporary binary file to log data to disk
            app.TempFilename = tempname; % create a random filename in the local appdata folder
            app.TempFile = fopen(app.TempFilename, 'w'); % w for write permission
            
            % Reset FIFO buffer data
            app.DataFIFOBuffer = [];
            app.TimestampsFIFOBuffer = [];
            
            % to avoid racing conditions, specify to the system that we
            % want to record any new data the next time that the DAQ starts
            % running. thus, we write this section of the code immediately
            % AFTER stopping the DAQ and resetting the buffer but BEFORE
            % trying to start the DAQ again
            app.isRecordingFlag = 1; 
            
            % reset all comment flags, queues, and tables to their starting values.
            app.commentID = 1; 
            app.queue_comment = 0;
            app.comment_buffer = cell(1,3);
            app.UITable_commentHistory.Data = cell2table(cell(1,2));
            
            try
                start(app.DAQ,'continuous');
            catch exception
                % In case of error show it and revert the changes
                uialert(app.UIFigure, exception.message, 'Start error');   
                setAppViewState(app, 'configuration'); 
                app.isRecordingFlag = 0; 
            end
            
            % update the gui to clear any log status text
            app.LogStatusEditField.Value = '';
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % update the display to remove the ability of the user to
            % interact with the gui until the file has been saved
            app.currentViewState = 'filesave';
            setAppViewState(app)
            stop(app.DAQ)
            
            % stop writing data to the binary file after stopping the DAQ
            app.isRecordingFlag = 0; 
    
            % Save logged data to MAT file (unless the user clicks Cancel in the "Save As" dialog)
            
            % Close temporary binary file
            fclose(app.TempFile);
                       
            % Gather metadata in preparation for saving to MAT file
            % append the comment meta data to the end of relevant metadata
            % properties
            deviceInfo = get(app.Devices.DeviceInfo(app.DeviceDropDown.Value-1));
            deviceInfo.Vendor = get(deviceInfo.Vendor);
            deviceInfo = rmfield(deviceInfo, 'Subsystems');
            metadata.DeviceInfo = deviceInfo;
            d = app.DAQ.Channels; % create a simpler variable handle for the following metadata info
            metadata.ChannelID = [{d.ID} {'comment button'}];
            metadata.ChannelName = [{d.Name} {'comments'}];
            metadata.ChannelGain = [app.active_channel_gains 1];
            metadata.MeasurementType = [{d.MeasurementType} {'user input'}];
            metadata.TerminalConfig = [{d.TerminalConfig} {'none'}];
%             metadata.Range = {d.Range}; % issues saving and maybe not necessary right now
            metadata.Rate = app.RatescanssEditField.Value;
            metadata.TriggerTime = datetime(app.TriggerTime, 'ConvertFrom', 'datenum', 'TimeZone', 'local');
            metadata.Comments = app.comment_buffer;
            
            % Open "Save As" to request destination MAT file path and file name from user
            [filename, pathname] = uiputfile({'*.mat'}, 'Save as',...
                fullfile(app.Filepath, app.Filename));
            
            if ~(isequal(filename,0) || isequal(pathname,0))
                % User specified a file name in a folder with write permission
                app.Filename = filename;
                app.Filepath = pathname;
                cancelSaveAs = false;
            else
                %  User clicked Cancel in "Save As" dialog
                cancelSaveAs = true;
            end
            
            if ~cancelSaveAs
                % Convert data from binary file to MAT file
                matFilepath = fullfile(app.Filepath, app.Filename);
                app.LogStatusEditField.Value = 'Saving data to MAT file is in progress...';
                drawnow
                
                % the number of columns should be equal to the number of
                % active channels + 1 time column + 1 comment column
                numColumns = numel(app.active_channel_names)+2;
                binFile2MAT(app, app.TempFilename, matFilepath, numColumns, metadata);
                app.LogStatusEditField.Value = sprintf('Saving data to ''%s'' file has completed.', app.Filename);
                
            else
                % User clicked Cancel in "Save As" dialog
                % Inform user that data has not been saved
                app.LogStatusEditField.Value = 'Saving data to MAT file was cancelled.';
            end
            
            % RESTART THE DAQ ---------------------------------------------
            % restart the DAQ acquisition after saving the data. this app
            % should be running constantly in the background, even while
            % not storing data. This is for simplicity. In theory,the user
            % should only be able to click the stop button that triggers
            % this callback by first adding at least one active channel to
            % the DAQ device. Thus, we should be able to restart no matter
            % the circumstance for clicking the stop button. 
            
            % Reset FIFO buffer data
            app.DataFIFOBuffer = [];
            app.TimestampsFIFOBuffer = [];
            
            try
                start(app.DAQ,'continuous');
            catch exception
                % In case of error show it and revert the change
                uialert(app.UIFigure, exception.message, 'Start error');   
                setAppViewState(app, 'configuration'); 
            end
            
            % reset the front panel for configuration
            app.currentViewState = 'configuration';
            setAppViewState(app)
        end

        % Button pushed function: CommentButton
        function CommentButtonPushed(app, event)
            % when the user wants to make a comment that is recorded in
            % time with the rest of the data, the user will click the
            % comment button. The function of this callback is to flag the
            % scans_available callback function for the DAQ that it should
            % store the current comment index to the data buffer (appended
            % at the very end)
            
            % immediately queue a comment to be added to the data buffer
            % and grab current comment IDX before QUEUE to avoid racing
            % conditions causing mismatch
            commentIDX = app.commentID;
            app.queue_comment = 1;
            
            % POP up menu with text box to store string for the comment.
            current_time = datestr(datetime('now')); % get the time of the comment
            prompt = {'Enter your comment:'};
            dlgtitle = 'Add a comment';
            dims = [1 75];
            definput = {'comment text'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            
            % display latest comment info in the log status text window
            update_text = ['(' current_time ') ' answer{:}];
            app.LogStatusEditField.Value = update_text;
            
            % update the buffer with the latest comment details
            app.comment_buffer(commentIDX,1) = {commentIDX};
            app.comment_buffer(commentIDX,2) = {current_time};
            app.comment_buffer(commentIDX,3) = answer;
            
            % insert the comments into the comment history table
            app.UITable_commentHistory.Data = cell2table(app.comment_buffer(:,2:end));
            
        end

        % Value changed function: YmaxEditField, YminEditField
        function YmaxminValueChanged(app, event)
           % Shared callback for YmaxEditField and YminEditField
            
            % get min max values and scale for y-units
            ymin = app.YminEditField.Value;
            ymax = app.YmaxEditField.Value;
            if ymax>ymin
                ylim(app.UIAxes, [ymin, ymax]);
            else
                % If new limits are not correct, revert the change
                event.Source.Value = event.PreviousValue;
            end 
        end

        % Value changed function: AutoscaleYSwitch
        function AutoscaleYSwitchValueChanged(app, event)
            updateAutoscaleYSwitchComponents(app)
        end

        % Menu selected function: SaveSettingsMenu
        function SaveSettingsMenuSelected(app, event)
            % propmpt the user  to save the settings at the given file
            % location
            file = uiputfile({'*.mat'}, 'Save settings as',...
                fullfile(app.Filepath, 'settings'));
            
            if file 
                % get all application  properties and variables
                props = properties(app);
                
                % init states that we would like to save (e.g., values and
                % enabled/disabled status)
                values = cell(length(props),1);
                enabled = cell(length(props),1);
                items = cell(length(props),1);
                data = cell(length(props),1);
                
                % for every app property, get the property name, value, and
                % enabled state and store them in the previously initialized
                % cell arrays
                for i = 1:length(props)
                    propName = props{i};
                    property = app.(propName);
                    if isprop(property, 'Value')
                        values{i} = app.(propName).Value;
                    end
                    if isprop(property, 'Enable')
                        enabled{i} = app.(props{i}).Enable;
                    end
                    if isprop(property, 'Items')
                        items{i} = app.(props{i}).Items;
                    end
                    if isprop(property, 'Data')
                        data{i} = app.(props{i}).Data;
                    end
                end
                
                % write the file 
                save(file, 'props', 'values', 'enabled', 'items', 'data');
            end
        end

        % Menu selected function: LoadSettingsMenu
        function LoadSettingsMenuSelected(app, event)
            % only allow the user to load settings if the system is not
            % currently recording! This should be edited in the system
            % setappview state
            
            % allow the user to select a settings file to load, if they
            % wish. otherwise do nothing
            file = uigetfile('*.mat', "Load Message");
            
            if file
               % if the user selected a valid file, then load the settings
               % to the settings variable and run the load function
                app.Settings = load(file);
                
                % the file that the user selected must be a settings file,
                % if it is not, then we need to alert the user and abort
                % the load
                if isfield(app.Settings,'props')
                    loadSettings(app);
                else
                    uialert(app.UIFigure,...
                        'User selected settings file does not contain the expected file structure! Load aborted',...
                        'Invalid settings file')
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadSettingsMenu = uimenu(app.FileMenu);
            app.LoadSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSettingsMenuSelected, true);
            app.LoadSettingsMenu.Text = 'Load Settings';
            app.SaveSettingsMenu = uimenu(app.FileMenu);
            app.SaveSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSettingsMenuSelected, true);
            app.SaveSettingsMenu.Text = 'Save Settings';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Add'; 'ChannelID'; 'Name'; 'Signal Gain'; 'Measurement Type'; 'Terminal Config'; 'Input Range'; 'Coupling'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true false true true true true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.setthescanratePanel = uipanel(app.DataTab);
            app.setthescanratePanel.Title = 'set the scan rate';
            app.RatescanssEditFieldLabel = uilabel(app.setthescanratePanel);
            app.RatescanssEditFieldLabel.Text = 'Rate (scans/s)';
            app.RatescanssEditField = uieditfield(app.setthescanratePanel, 'numeric');
            app.RatescanssEditField.ValueChangedFcn = createCallbackFcn(app, @RatescanssEditFieldValueChanged, true);
            app.RatescanssEditField.Value = 10;
            app.RateMinEditFieldLabel = uilabel(app.setthescanratePanel);
            app.RateMinEditFieldLabel.Text = 'Rate Min';
            app.RateMinEditField = uieditfield(app.setthescanratePanel, 'numeric');
            app.RateMinEditField.Editable = 'off';
            app.RateMinEditField.Value = 0.1;
            app.RateMaxEditFieldLabel = uilabel(app.setthescanratePanel);
            app.RateMaxEditFieldLabel.Text = 'Rate Max';
            app.RateMaxEditField = uieditfield(app.setthescanratePanel, 'numeric');
            app.RateMaxEditField.Editable = 'off';
            app.RateMaxEditField.Value = 100000;
            app.selecttheNIdevicePanel = uipanel(app.DataTab);
            app.selecttheNIdevicePanel.Title = 'select the NI device';
            app.DeviceDropDownLabel = uilabel(app.selecttheNIdevicePanel);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.selecttheNIdevicePanel);
            app.DeviceDropDown.Items = {'Detecting devices...'};
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceDropDownValueChanged, true);
            app.DeviceDropDown.Value = 'Detecting devices...';
            app.recorddataandmakecommentsPanel = uipanel(app.DataTab);
            app.recorddataandmakecommentsPanel.Title = 'record data and make comments';
            app.RecordButton = uibutton(app.recorddataandmakecommentsPanel, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.StopButton = uibutton(app.recorddataandmakecommentsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.CommentButton = uibutton(app.recorddataandmakecommentsPanel, 'push');
            app.CommentButton.ButtonPushedFcn = createCallbackFcn(app, @CommentButtonPushed, true);
            app.CommentButton.Text = 'Comment';
            app.LogStatusEditFieldLabel = uilabel(app.recorddataandmakecommentsPanel);
            app.LogStatusEditFieldLabel.Text = 'Log Status';
            app.LogStatusEditField = uieditfield(app.recorddataandmakecommentsPanel, 'text');
            app.LogStatusEditField.Editable = 'off';
            app.PlotsettingsTab = uitab(app.TabGroup);
            app.PlotsettingsTab.Title = 'Plot settings';
            app.xaxisspecifytimerangetodisplayPanel = uipanel(app.PlotsettingsTab);
            app.xaxisspecifytimerangetodisplayPanel.Title = 'x-axis: specify time range to display';
            app.TimewindowEditFieldLabel = uilabel(app.xaxisspecifytimerangetodisplayPanel);
            app.TimewindowEditFieldLabel.Text = 'Time window';
            app.TimewindowEditField = uieditfield(app.xaxisspecifytimerangetodisplayPanel, 'numeric');
            app.TimewindowEditField.Value = 30;
            app.xUnitsDropDownLabel = uilabel(app.xaxisspecifytimerangetodisplayPanel);
            app.xUnitsDropDownLabel.Text = 'xUnits';
            app.xUnitsDropDown = uidropdown(app.xaxisspecifytimerangetodisplayPanel);
            app.xUnitsDropDown.Items = {'Seconds (s)', 'Minutes (min)'};
            app.xUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @xUnitsDropDownValueChanged, true);
            app.xUnitsDropDown.Value = 'Seconds (s)';
            app.yaxisselectactivechannelstodisplayandsetthevoltagescalePanel = uipanel(app.PlotsettingsTab);
            app.yaxisselectactivechannelstodisplayandsetthevoltagescalePanel.Title = 'y-axis: select active channels to display and set the voltage scale';
            app.activeplotsListBoxLabel = uilabel(app.yaxisselectactivechannelstodisplayandsetthevoltagescalePanel);
            app.activeplotsListBoxLabel.Text = 'active plots';
            app.activeplotsListBox = uilistbox(app.yaxisselectactivechannelstodisplayandsetthevoltagescalePanel);
            app.activeplotsListBox.Items = {};
            app.activeplotsListBox.Multiselect = 'on';
            app.activeplotsListBox.ValueChangedFcn = createCallbackFcn(app, @activeplotsListBoxValueChanged, true);
            app.activeplotsListBox.Tooltip = {'Select the channels to display on the plot window'};
            app.activeplotsListBox.Value = {};
            app.AutoscaleYSwitchLabel = uilabel(app.yaxisselectactivechannelstodisplayandsetthevoltagescalePanel);
            app.AutoscaleYSwitchLabel.Text = 'Autoscale Y';
            app.AutoscaleYSwitch = uiswitch(app.yaxisselectactivechannelstodisplayandsetthevoltagescalePanel, 'slider');
            app.AutoscaleYSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoscaleYSwitchValueChanged, true);
            app.AutoscaleYSwitch.Value = 'On';
            app.YmaxEditFieldLabel = uilabel(app.yaxisselectactivechannelstodisplayandsetthevoltagescalePanel);
            app.YmaxEditFieldLabel.Text = 'Ymax';
            app.YmaxEditField = uieditfield(app.yaxisselectactivechannelstodisplayandsetthevoltagescalePanel, 'numeric');
            app.YmaxEditField.ValueChangedFcn = createCallbackFcn(app, @YmaxminValueChanged, true);
            app.YmaxEditField.Value = 1;
            app.YminEditFieldLabel = uilabel(app.yaxisselectactivechannelstodisplayandsetthevoltagescalePanel);
            app.YminEditFieldLabel.Text = 'Ymin';
            app.YminEditField = uieditfield(app.yaxisselectactivechannelstodisplayandsetthevoltagescalePanel, 'numeric');
            app.YminEditField.ValueChangedFcn = createCallbackFcn(app, @YmaxminValueChanged, true);
            app.YminEditField.Value = -1;
            app.yUnitsDropDownLabel = uilabel(app.yaxisselectactivechannelstodisplayandsetthevoltagescalePanel);
            app.yUnitsDropDownLabel.Text = 'yUnits';
            app.yUnitsDropDown = uidropdown(app.yaxisselectactivechannelstodisplayandsetthevoltagescalePanel);
            app.yUnitsDropDown.Items = {'Volts (V)', 'Millivolts (mV)', 'Microvolts (uV)'};
            app.yUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @yUnitsDropDownValueChanged, true);
            app.yUnitsDropDown.Value = 'Millivolts (mV)';
            app.memoryallocationPanel = uipanel(app.PlotsettingsTab);
            app.memoryallocationPanel.Title = 'memory allocation';
            app.FIFOmaxbuffersizeEditFieldLabel = uilabel(app.memoryallocationPanel);
            app.FIFOmaxbuffersizeEditFieldLabel.Text = 'FIFO max buffer size';
            app.FIFOmaxbuffersizeEditField = uieditfield(app.memoryallocationPanel, 'numeric');
            app.FIFOmaxbuffersizeEditField.Value = 1000000;
            app.CommenthistoryTab = uitab(app.TabGroup);
            app.CommenthistoryTab.Title = 'Comment history';
            app.UITable_commentHistory = uitable(app.CommenthistoryTab);
            app.UITable_commentHistory.ColumnName = {'Comment time'; 'Comment'};
            app.UITable_commentHistory.RowName = {};
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Voltage (V)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end